classdef AdjustVision_Figure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Image        matlab.ui.control.Image
        ImageThresh  matlab.ui.control.Image
        hueUpper     matlab.ui.control.Slider
        hueLower     matlab.ui.control.Slider
        satUpper     matlab.ui.control.Slider
        satLower     matlab.ui.control.Slider
        valUpper     matlab.ui.control.Slider
        valLower     matlab.ui.control.Slider
        text3_6      matlab.ui.control.Label
        text3_5      matlab.ui.control.Label
        text3_4      matlab.ui.control.Label
        text3_3      matlab.ui.control.Label
        text3_2      matlab.ui.control.Label
        text3        matlab.ui.control.Label
        saveButton   matlab.ui.control.Button
    end

    
    properties (Access = public)
        buttonVal logical
    end
    
    methods (Access = public)
        
        function ret = getSliderValues(app)
            hueU = get(app.hueUpper,'Value');
            hueL = get(app.hueLower,'Value');
            satU = get(app.satUpper,'Value');
            satL = get(app.satLower,'Value');
            valU = get(app.valUpper,'Value');
            valL = get(app.valLower,'Value');
            ret = [hueU,hueL,satU,satL,valU,valL];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AdjustVision_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   unrecognized PropertyName/PropertyValue pairs from the
            %            command line (see VARARGIN)
            
            % Choose default command line output for AdjustVision
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            app.buttonVal = false;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.buttonVal = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'AdjustVision';
            app.figure1.Tag = 'figure1';
            app.GridLayout = uigridlayout(app.figure1);
            app.saveButton = uibutton(app.GridLayout, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Tag = 'saveButton';
            app.saveButton.Text = 'Save & Exit';
            app.text3 = uilabel(app.GridLayout);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Hue Upper';
            app.text3_2 = uilabel(app.GridLayout);
            app.text3_2.Tag = 'text3';
            app.text3_2.Text = 'Hue Lower';
            app.text3_3 = uilabel(app.GridLayout);
            app.text3_3.Tag = 'text3';
            app.text3_3.Text = 'Saturation Upper';
            app.text3_4 = uilabel(app.GridLayout);
            app.text3_4.Tag = 'text3';
            app.text3_4.Text = 'Saturation Lower';
            app.text3_5 = uilabel(app.GridLayout);
            app.text3_5.Tag = 'text3';
            app.text3_5.Text = 'Value Upper';
            app.text3_6 = uilabel(app.GridLayout);
            app.text3_6.Tag = 'text3';
            app.text3_6.Text = 'Value Lower';
            app.valLower = uislider(app.GridLayout);
            app.valLower.Limits = [0 1];
            app.valLower.MajorTicks = [];
            app.valLower.MinorTicks = [];
            app.valLower.Tag = 'hueLower';
            app.valLower.Value = 1;
            app.valUpper = uislider(app.GridLayout);
            app.valUpper.Limits = [0 1];
            app.valUpper.MajorTicks = [];
            app.valUpper.MinorTicks = [];
            app.valUpper.Tag = 'satUpper';
            app.valUpper.Value = 0.8;
            app.satLower = uislider(app.GridLayout);
            app.satLower.Limits = [0 1];
            app.satLower.MajorTicks = [];
            app.satLower.MinorTicks = [];
            app.satLower.Tag = 'satLower';
            app.satLower.Value = 1;
            app.satUpper = uislider(app.GridLayout);
            app.satUpper.Limits = [0 1];
            app.satUpper.MajorTicks = [];
            app.satUpper.MinorTicks = [];
            app.satUpper.Tag = 'valUpper';
            app.satUpper.Value = 0.6;
            app.hueLower = uislider(app.GridLayout);
            app.hueLower.Limits = [0 1];
            app.hueLower.MajorTicks = [];
            app.hueLower.MinorTicks = [];
            app.hueLower.Tag = 'valLower';
            app.hueLower.Value = 1;
            app.hueUpper = uislider(app.GridLayout);
            app.hueUpper.Limits = [0 1];
            app.hueUpper.MajorTicks = [];
            app.hueUpper.MinorTicks = [];
            app.hueUpper.Tag = 'hueUpper';
            app.hueUpper.Value = 0.06;
            app.ImageThresh = uiimage(app.GridLayout);
            app.Image = uiimage(app.GridLayout);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdjustVision_Figure(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)AdjustVision_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end